classdef LensSimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure  matlab.ui.Figure
        copyBtn               matlab.ui.control.Button
        statusLbl             matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        RayDiagramTab         matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        PerformancePlotsTab   matlab.ui.container.Tab
        UIAxesPerf            matlab.ui.control.UIAxes
        OutputPanel           matlab.ui.container.Panel
        outBox                matlab.ui.control.TextArea
        ChangeInputsthenRUNTextAreaLabel  matlab.ui.control.Label
        InputsPanel           matlab.ui.container.Panel
        f2Ed                  matlab.ui.control.NumericEditField
        FocalLengthLabel_3    matlab.ui.control.Label
        f1Ed                  matlab.ui.control.NumericEditField
        FocalLengthLabel_2    matlab.ui.control.Label
        runBtn                matlab.ui.control.Button
        modeDrop              matlab.ui.control.DropDown
        ModeLabel             matlab.ui.control.Label
        dEd                   matlab.ui.control.NumericEditField
        LensseparationdLabel  matlab.ui.control.Label
        uEd                   matlab.ui.control.NumericEditField
        Focallengthf1Label    matlab.ui.control.Label
        hEd                   matlab.ui.control.NumericEditField
        FocalLengthLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Default values
    if app.uEd.Value == 0,  app.uEd.Value  = 20; end
    if app.hEd.Value == 0,  app.hEd.Value  = 5;  end
    if app.f1Ed.Value == 0, app.f1Ed.Value = 10; end
    if app.f2Ed.Value == 0, app.f2Ed.Value = 12; end
    if app.dEd.Value == 0,  app.dEd.Value  = 25; end

    modeDropValueChanged(app);
    runBtnButtonPushed(app, []);

        end

        % Button pushed function: runBtn
        function runBtnButtonPushed(app, event)
            app.statusLbl.Text = 'Running...';

u  = app.uEd.Value;
h  = app.hEd.Value;
f1 = app.f1Ed.Value;
f2 = app.f2Ed.Value;
d  = app.dEd.Value;

mode = 1;
if strcmp(app.modeDrop.Value,'Two Lenses')
    mode = 2;
end

try
    out = lensCalcSafe(u,h,f1,f2,d,mode);

    if out.hasInfinity
        cla(app.UIAxes);
        drawSetupOnly(app.UIAxes,u,h,f1,f2,d,mode, out.status, out);

    else
        drawRayDiagram(app.UIAxes,u,h,f1,f2,d,mode,out);
    end

    plotPerformance(app.UIAxesPerf, u, h, f1, f2, d, mode);

    app.outBox.Value = out.report;
    app.statusLbl.Text = out.status;

catch ME
    cla(app.UIAxes);
    cla(app.UIAxesPerf);
    app.outBox.Value = { 'Error:', ME.message };
    app.statusLbl.Text = 'Error.';
end

        end

        % Value changed function: modeDrop
        function modeDropValueChanged(app, event)
isTwo = strcmp(app.modeDrop.Value,'Two Lenses');
app.f2Ed.Enable = tern(isTwo,'on','off');
app.dEd.Enable  = tern(isTwo,'on','off');
app.statusLbl.Text = tern(isTwo,'Mode: Two Lenses','Mode: Single Lens');

        end

        % Button pushed function: copyBtn
        function copyBtnButtonPushed(app, event)
            txt = strjoin(string(app.outBox.Value), newline);
clipboard('copy', txt);
app.statusLbl.Text = 'Output copied.';

        end

        % Callback function
        function SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigureSizeChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure = uifigure('Visible', 'off');
            app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure.Name = 'Simulation and Analysis of Single and Two-Lens Optical Systems';
            app.InputsPanel = uipanel(app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.FocalLengthLabel = uilabel(app.InputsPanel);
            app.FocalLengthLabel.Text = 'Object height,h(m)';
            app.hEd = uieditfield(app.InputsPanel, 'numeric');
            app.hEd.ValueDisplayFormat = '%.6g';
            app.Focallengthf1Label = uilabel(app.InputsPanel);
            app.Focallengthf1Label.Text = 'Object distance,u(m)';
            app.uEd = uieditfield(app.InputsPanel, 'numeric');
            app.uEd.ValueDisplayFormat = '%.6g';
            app.LensseparationdLabel = uilabel(app.InputsPanel);
            app.LensseparationdLabel.Text = 'Lens separation, d(m)';
            app.dEd = uieditfield(app.InputsPanel, 'numeric');
            app.dEd.ValueDisplayFormat = '%.6g';
            app.ModeLabel = uilabel(app.InputsPanel);
            app.ModeLabel.Text = 'Mode';
            app.modeDrop = uidropdown(app.InputsPanel);
            app.modeDrop.Items = {'Single Lens', 'Two Lenses'};
            app.modeDrop.ValueChangedFcn = createCallbackFcn(app, @modeDropValueChanged, true);
            app.modeDrop.Value = 'Single Lens';
            app.runBtn = uibutton(app.InputsPanel, 'push');
            app.runBtn.ButtonPushedFcn = createCallbackFcn(app, @runBtnButtonPushed, true);
            app.runBtn.Text = 'RUN';
            app.FocalLengthLabel_2 = uilabel(app.InputsPanel);
            app.FocalLengthLabel_2.Text = 'Focal length,f1(m)';
            app.f1Ed = uieditfield(app.InputsPanel, 'numeric');
            app.f1Ed.ValueDisplayFormat = '%.6g';
            app.FocalLengthLabel_3 = uilabel(app.InputsPanel);
            app.FocalLengthLabel_3.Text = 'Focal length,f2(m)';
            app.f2Ed = uieditfield(app.InputsPanel, 'numeric');
            app.f2Ed.ValueDisplayFormat = '%.6g';
            app.OutputPanel = uipanel(app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure);
            app.OutputPanel.Title = 'Output';
            app.ChangeInputsthenRUNTextAreaLabel = uilabel(app.OutputPanel);
            app.ChangeInputsthenRUNTextAreaLabel.Text = 'Change Inputs, then RUN';
            app.outBox = uitextarea(app.OutputPanel);
            app.outBox.Editable = 'off';
            app.TabGroup = uitabgroup(app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure);
            app.RayDiagramTab = uitab(app.TabGroup);
            app.RayDiagramTab.Title = 'Ray Diagram';
            app.UIAxes = uiaxes(app.RayDiagramTab);
            title(app.UIAxes, 'Ray Diagram')
            xlabel(app.UIAxes, 'x(m)')
            ylabel(app.UIAxes, 'Height(m)')
            zlabel(app.UIAxes, 'Z')
            app.PerformancePlotsTab = uitab(app.TabGroup);
            app.PerformancePlotsTab.Title = 'Performance Plots';
            app.UIAxesPerf = uiaxes(app.PerformancePlotsTab);
            title(app.UIAxesPerf, 'Magnification vs Object Distance')
            xlabel(app.UIAxesPerf, 'Object Distance, u(m)')
            ylabel(app.UIAxesPerf, '|Magnification|')
            zlabel(app.UIAxesPerf, 'Z')
            app.statusLbl = uilabel(app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure);
            app.statusLbl.Text = 'Ready';
            app.copyBtn = uibutton(app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure, 'push');
            app.copyBtn.ButtonPushedFcn = createCallbackFcn(app, @copyBtnButtonPushed, true);
            app.copyBtn.Text = 'Copy Output';
            % Show the figure after all components are created
            app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LensSimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationandAnalysisofSingleandTwoLensOpticalSystemsUIFigure)
        end
    end
end